classdef GridNMeshforMW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PlotButton           matlab.ui.control.Button
        DGridEditField       matlab.ui.control.EditField
        DGridEditFieldLabel  matlab.ui.control.Label
        GridEditField        matlab.ui.control.EditField
        GridEditFieldLabel   matlab.ui.control.Label
        MeshEditField        matlab.ui.control.EditField
        MeshEditFieldLabel   matlab.ui.control.Label
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        triangles % Description
        xygrid  % Description
        Vol % Description
        CallingApp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, triangles, xygrid, Vol)
            app.CallingApp = MainApp;
            app.triangles = triangles;
            app.xygrid = xygrid;
            app.Vol = Vol;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
                triangle = load(app.triangles);
                xygrids = load(app.xygrid);
                triplot(triangle, xygrids(:,1),xygrids(:,2), 'parent', app.UIAxes)
                xlabel(app.UIAxes,'x-axis','Interpreter','latex')
                ylabel(app.UIAxes, "y-axis",'Interpreter',"latex")
                zlabel(app.UIAxes, "z-axis",'Interpreter',"latex")
                title(app.UIAxes, "Mesh xygrid.out","Interpreter","latex"
        
                xygridss = load(app.xygrid);
                plot(app.UIAxes2, xygridss(:,1),xygridss(:,2));
                xlabel(app.UIAxes2, 'x-axis','Interpreter','latex')
                ylabel(app.UIAxes2, "y-axis",'Interpreter',"latex")
                title(app.UIAxes2, 'xygrid','Interpreter',"latex")
                            
                triangless = app.triangles;
                xygridsss = app.xygrid;
                Vols = app.Vol;
                trisurf(triangless,xygridsss(:,1),xygridsss(:,2),Vols, 'parent', app.UIAxes3);
                xlabel(app.UIAxes3, 'x-axis','Interpreter','latex')
                ylabel(app.UIAxes3, "y-axis",'Interpreter',"latex")
                zlabel(app.UIAxes3, "Volume",'Interpreter',"latex")
                title(app.UIAxes3, 'xygrid','Interpreter',"latex")


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.MeshEditFieldLabel = uilabel(app.UIFigure);
            app.MeshEditFieldLabel.Text = 'Mesh';
            app.MeshEditField = uieditfield(app.UIFigure, 'text');
            app.MeshEditField.Editable = 'off';
            app.GridEditFieldLabel = uilabel(app.UIFigure);
            app.GridEditFieldLabel.Text = 'Grid';
            app.GridEditField = uieditfield(app.UIFigure, 'text');
            app.GridEditField.Editable = 'off';
            app.DGridEditFieldLabel = uilabel(app.UIFigure);
            app.DGridEditFieldLabel.Text = '3-D Grid';
            app.DGridEditField = uieditfield(app.UIFigure, 'text');
            app.DGridEditField.Editable = 'off';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GridNMeshforMW(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end