classdef Trial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        LevelsSliderLabel  matlab.ui.control.Label
        LevelsSlider       matlab.ui.control.Slider
        LoadDataButton_3   matlab.ui.control.Button
        UIAxes_4           matlab.ui.control.UIAxes
        UIAxes_3           matlab.ui.control.UIAxes
    end

    properties (Access = private)
        gdata % imported g-file
    end
    
    methods (Access = private)
        
        function contourplotmaker(app)
        A = app.gdata;
        R = linspace(A.rleft,A.rleft+A.rdim,size(A.psizr,1));
        Z = linspace(-A.zdim./2,A.zdim./2,size(A.psizr,2));
        contour(app.UIAxes_4,R,Z,A.psizr', app.LevelsSlider.Value)
        colorbar(app.UIAxes_4)
        hold(app.UIAxes_4, 'on')
        plot(app.UIAxes_4,A.rlim,A.zlim,'magenta','LineWidth',2);
        plot(app.UIAxes_4,A.rbbbs,A.zbbbs,'k','LineWidth',2);
        hold(app.UIAxes_4, 'off')
            
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton_3
        function LoadDataButton_3Pushed(app, event)
            [filename, pathname] = uigetfile('*.*');
            if ~isempty(filename)
               A = readEFITgfile(sprintf('%s%s',pathname,filename));
               plot(app.UIAxes_3,abs(A.fpol),'g')
               app.gdata = A;
               app.contourplotmaker()
            end
        end

        % Value changed function: LevelsSlider
        function LevelsSliderValueChanged(app, event)
           app.contourplotmaker()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'F-Pol')
            xlabel(app.UIAxes_3, 'N_{\psi}')
            ylabel(app.UIAxes_3, 'F (in mT)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Z vs. R ')
            xlabel(app.UIAxes_4, 'R (in m)')
            ylabel(app.UIAxes_4, 'Z (in m)')
            zlabel(app.UIAxes_4, 'Z')
            app.LoadDataButton_3 = uibutton(app.UIFigure, 'push');
            app.LoadDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButton_3Pushed, true);
            app.LoadDataButton_3.Text = 'Load Data';
            app.LevelsSlider = uislider(app.UIFigure);
            app.LevelsSlider.ValueChangedFcn = createCallbackFcn(app, @LevelsSliderValueChanged, true);
            app.LevelsSlider.Value = 40;
            app.LevelsSliderLabel = uilabel(app.UIFigure);
            app.LevelsSliderLabel.Text = 'Levels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end