classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ManualButton                 matlab.ui.control.Button
        LoadPathButton               matlab.ui.control.Button
        TimeSeriesButton             matlab.ui.control.Button
        NeuralNetworkTestButton      matlab.ui.control.Button
        GridandMeshButton            matlab.ui.control.Button
        SimulationEquilibriumButton  matlab.ui.control.Button
        ExperimentalDataButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        folder % specified folder
        gfilepath % found g-file path
        ExperimentalChildApp % app for experimental data
        GridNMeshApp % app for grid and mesh plots
        triangles % found triangles file path
        xygrid % found xygrid file path
        Vol % found Vol10 file path

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadPathButton
        function LoadPathButtonPushed(app, event)
            app.folder = uigetdir();
            temparray = dir(fullfile(app.folder,'g*'));
            if isempty(temparray)
            else
            

            for i = 1:length(temparray) 
            if cell2mat(regexp(char(temparray(i).name), 'g\d*[.]\d*', 'match'))
                index = i;
            end
            end
            app.gfilepath = fullfile(temparray(index).folder,temparray(index).name);
            app.ExperimentalDataButton.Enable = 'on';
            end

            temparray2 = dir(fullfile(app.folder, '**', 'triangles'));
            temparray3 = dir(fullfile(app.folder, '**', 'xygrid')); 
            temparray4 = dir(fullfile(app.folder, '**', 'Vol10'));
            if isempty(temparray2)
            else 
                app.triangles = fullfile(temparray2.folder, temparray2.name);
            end
            if isempty(temparray3)
            else
                app.xygrid = fullfile(temparray3.folder, temparray3.name);
            end
            if isempty(temparray4)
            else
                app.Vol = fullfile(temparray4.folder, temparray4.name);
            end


        end

        % Button pushed function: ExperimentalDataButton
        function ExperimentalDataButtonPushed(app, event)
            app.ExperimentalChildApp = trial2(app,app.gfilepath);
        end

        % Button pushed function: GridandMeshButton
        function GridandMeshButtonPushed(app, event)
            app.GridNMeshApp = GridNMeshforMW(app, (app.triangles), (app.xygrid), (app.Vol));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExperimentalDataButton = uibutton(app.UIFigure, 'push');
            app.ExperimentalDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExperimentalDataButtonPushed, true);
            app.ExperimentalDataButton.Enable = 'off';
            app.ExperimentalDataButton.Text = 'Experimental Data';
            app.SimulationEquilibriumButton = uibutton(app.UIFigure, 'push');
            app.SimulationEquilibriumButton.Enable = 'off';
            app.SimulationEquilibriumButton.Text = 'Simulation Equilibrium';
            app.GridandMeshButton = uibutton(app.UIFigure, 'push');
            app.GridandMeshButton.ButtonPushedFcn = createCallbackFcn(app, @GridandMeshButtonPushed, true);
            app.GridandMeshButton.Text = 'Grid and Mesh';
            app.NeuralNetworkTestButton = uibutton(app.UIFigure, 'push');
            app.NeuralNetworkTestButton.Enable = 'off';
            app.NeuralNetworkTestButton.Text = 'Neural Network Test';
            app.TimeSeriesButton = uibutton(app.UIFigure, 'push');
            app.TimeSeriesButton.Enable = 'off';
            app.TimeSeriesButton.Text = 'Time-Series';
            app.LoadPathButton = uibutton(app.UIFigure, 'push');
            app.LoadPathButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPathButtonPushed, true);
            app.LoadPathButton.Text = 'Load Path';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.Enable = 'off';
            app.ManualButton.Text = 'Manual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end