classdef trial2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PlotDataButton     matlab.ui.control.Button
        LevelsSliderLabel  matlab.ui.control.Label
        LevelsSlider       matlab.ui.control.Slider
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        gdata % found g-data
        CallingApp % Description
        gpath % Description
    end
    
    methods (Access = private)
        
        function contourplotmaker(app)
        A = app.gdata;
        R = linspace(A.rleft,A.rleft+A.rdim,size(A.psizr,1));
        Z = linspace(-A.zdim./2,A.zdim./2,size(A.psizr,2));
        contour(app.UIAxes,R,Z,A.psizr', app.LevelsSlider.Value)
        colorbar(app.UIAxes)
        hold(app.UIAxes, 'on')
        plot(app.UIAxes,A.rlim,A.zlim,'magenta','LineWidth',2);
        plot(app.UIAxes,A.rbbbs,A.zbbbs,'k','LineWidth',2);
        hold(app.UIAxes, 'off')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, gpath)
            app.CallingApp = MainApp;
            app.gpath = gpath;
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            
               A = readEFITgfile(app.gpath);
               plot(app.UIAxes_2,abs(A.fpol),'g')
               app.gdata = A;
               app.contourplotmaker()
        end

        % Value changed function: LevelsSlider
        function LevelsSliderValueChanged(app, event)
             app.contourplotmaker()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Z vs. R ')
            xlabel(app.UIAxes, 'R (in m)')
            ylabel(app.UIAxes, 'Z (in m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'F-Pol')
            xlabel(app.UIAxes_2, 'N_{\psi}')
            ylabel(app.UIAxes_2, 'F (in mT)')
            zlabel(app.UIAxes_2, 'Z')
            app.LevelsSlider = uislider(app.UIFigure);
            app.LevelsSlider.ValueChangedFcn = createCallbackFcn(app, @LevelsSliderValueChanged, true);
            app.LevelsSlider.Value = 40;
            app.LevelsSliderLabel = uilabel(app.UIFigure);
            app.LevelsSliderLabel.Text = 'Levels';
            app.PlotDataButton = uibutton(app.UIFigure, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trial2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end